% MATLAB App Designer Code View for DroneLogisticsConfigurator.mlapp
classdef DroneLogisticsConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainGridLayout             matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        ScenarioLocationTab        matlab.ui.container.Tab
        ScenarioGridLayout         matlab.ui.container.GridLayout
        LatitudeLabel              matlab.ui.control.Label
        LatitudeInput              matlab.ui.control.NumericEditField
        LongitudeLabel             matlab.ui.control.Label
        LongitudeInput             matlab.ui.control.NumericEditField
        MapRadiusLabel             matlab.ui.control.Label
        MapRadiusInput             matlab.ui.control.NumericEditField
        NumCustomersLabel          matlab.ui.control.Label
        NumCustomersInput          matlab.ui.control.NumericEditField
        NumCandidateDepotsLabel    matlab.ui.control.Label
        NumCandidateDepotsInput    matlab.ui.control.NumericEditField
        NumHubsToSelectLabel       matlab.ui.control.Label
        NumHubsToSelectInput       matlab.ui.control.NumericEditField
        DroneParametersTab         matlab.ui.container.Tab
        DroneGridLayout            matlab.ui.container.GridLayout
        DronePayloadLabel          matlab.ui.control.Label
        DronePayloadInput          matlab.ui.control.NumericEditField
        DroneRangeLabel            matlab.ui.control.Label
        DroneRangeInput            matlab.ui.control.NumericEditField
        DroneUnitCostLabel         matlab.ui.control.Label
        DroneUnitCostInput         matlab.ui.control.NumericEditField
        SAParametersTab            matlab.ui.container.Tab
        SAGridLayout               matlab.ui.container.GridLayout
        SA_InitialTempLabel        matlab.ui.control.Label
        SA_InitialTempInput        matlab.ui.control.NumericEditField
        SA_FinalTempLabel          matlab.ui.control.Label
        SA_FinalTempInput          matlab.ui.control.NumericEditField
        SA_AlphaLabel              matlab.ui.control.Label
        SA_AlphaInput              matlab.ui.control.NumericEditField
        SA_MaxIterPerTempLabel     matlab.ui.control.Label
        SA_MaxIterPerTempInput     matlab.ui.control.NumericEditField
        ACOParametersTab           matlab.ui.container.Tab
        ACOGridLayout              matlab.ui.container.GridLayout
        ACO_NumAntsLabel           matlab.ui.control.Label
        ACO_NumAntsInput           matlab.ui.control.NumericEditField
        ACO_MaxIterationsLabel     matlab.ui.control.Label
        ACO_MaxIterationsInput     matlab.ui.control.NumericEditField
        ACO_RhoLabel               matlab.ui.control.Label
        ACO_RhoInput               matlab.ui.control.NumericEditField
        ACO_AlphaPheromoneLabel    matlab.ui.control.Label
        ACO_AlphaPheromoneInput    matlab.ui.control.NumericEditField
        ACO_BetaHeuristicLabel     matlab.ui.control.Label
        ACO_BetaHeuristicInput     matlab.ui.control.NumericEditField
        ACO_Tau0Label              matlab.ui.control.Label
        ACO_Tau0Input              matlab.ui.control.NumericEditField
        BottomPanelGridLayout      matlab.ui.container.GridLayout
        RunSimulationButton        matlab.ui.control.Button
        StatusTextArea             matlab.ui.control.TextArea
        LoadDefaultsButton         matlab.ui.control.Button % Added button
    end

    % Properties to store configuration
    properties (Access = private)
        ConfigParameters struct % Structure to hold all parameters
        ProjectBasePath string   % Path to the project root
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine project base path (assuming WebApp is one level down)
            % This path is used for saving/loading configurations relative to project root
            app.ProjectBasePath = fileparts(fileparts(mfilename('fullpath')));
            app.StatusTextArea.Value = {['Welcome! Project base path identified as: ', app.ProjectBasePath];
                                        'Configure parameters or load defaults.'};
            % Automatically load default parameters on startup
            loadDefaultParameters(app);
        end

        % Function to load default parameters
        function loadDefaultParameters(app, event)
            defaultParamFile = fullfile(app.ProjectBasePath, 'configurations', 'default_simulation_parameters.m');
            if exist(defaultParamFile, 'file')
                try
                    % Run the script to get the params structure
                    params = feval(extractBefore(defaultParamFile, '.m')); % feval calls the script

                    % --- Populate Scenario & Location Parameters ---
                    app.LatitudeInput.Value = params.Scenario.CenterLatitude;
                    app.LongitudeInput.Value = params.Scenario.CenterLongitude;
                    app.MapRadiusInput.Value = params.Scenario.MapRadiusKm;
                    app.NumCustomersInput.Value = params.Scenario.NumCustomers;
                    app.NumCandidateDepotsInput.Value = params.Scenario.NumCandidateDepots;
                    app.NumHubsToSelectInput.Value = params.Scenario.NumHubsToSelect;

                    % --- Populate Drone Parameters ---
                    app.DronePayloadInput.Value = params.Drone.PayloadCapacity;
                    app.DroneRangeInput.Value = params.Drone.MaxRangeKm;
                    app.DroneUnitCostInput.Value = params.Drone.UnitCostPerKm;

                    % --- Populate SA Parameters ---
                    app.SA_InitialTempInput.Value = params.SA.InitialTemp;
                    app.SA_FinalTempInput.Value = params.SA.FinalTemp;
                    app.SA_AlphaInput.Value = params.SA.Alpha;
                    app.SA_MaxIterPerTempInput.Value = params.SA.MaxIterPerTemp;

                    % --- Populate ACO Parameters ---
                    app.ACO_NumAntsInput.Value = params.ACO.NumAnts;
                    app.ACO_MaxIterationsInput.Value = params.ACO.MaxIterations;
                    app.ACO_RhoInput.Value = params.ACO.Rho_Evaporation;
                    app.ACO_AlphaPheromoneInput.Value = params.ACO.Alpha_PheromoneImportance;
                    app.ACO_BetaHeuristicInput.Value = params.ACO.Beta_HeuristicImportance;
                    app.ACO_Tau0Input.Value = params.ACO.Tau0_InitialPheromone;

                    app.StatusTextArea.Value = {'Default parameters loaded successfully.'};
                catch ME
                    app.StatusTextArea.Value = {'Error loading default parameters:', ME.message};
                    uialert(app.UIFigure, ['Error loading default_simulation_parameters.m: ' ME.message], 'Loading Error', 'Icon', 'error');
                end
            else
                app.StatusTextArea.Value = {['Default parameter file not found: ', defaultParamFile]};
                 uialert(app.UIFigure, ['Default parameter file not found: ', defaultParamFile newline 'Please create it in the configurations folder.'], 'File Not Found', 'Icon', 'warning');
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.StatusTextArea.Value = {'Collecting parameters...'};
            drawnow; % Update text area immediately

            try
                params = struct(); % Initialize params structure

                % --- Collect Scenario & Location Parameters ---
                params.Scenario.CenterLatitude = app.LatitudeInput.Value;
                params.Scenario.CenterLongitude = app.LongitudeInput.Value;
                params.Scenario.MapRadiusKm = app.MapRadiusInput.Value;
                params.Scenario.NumCustomers = round(app.NumCustomersInput.Value); % Ensure integer
                if params.Scenario.NumCustomers <= 0
                    error('Number of customers must be positive.');
                end
                params.Scenario.NumCandidateDepots = round(app.NumCandidateDepotsInput.Value); % Ensure integer
                params.Scenario.NumHubsToSelect = round(app.NumHubsToSelectInput.Value); % Ensure integer
                if params.Scenario.NumHubsToSelect <=0
                    error('Number of hubs to select must be positive.');
                end
                if params.Scenario.NumHubsToSelect > params.Scenario.NumCandidateDepots && params.Scenario.NumCandidateDepots > 0
                     error('Number of hubs to select cannot exceed candidate depots.');
                end


                % --- Collect Drone Parameters ---
                params.Drone.PayloadCapacity = app.DronePayloadInput.Value;
                if params.Drone.PayloadCapacity <= 0
                    error('Drone payload capacity must be positive.');
                end
                params.Drone.MaxRangeKm = app.DroneRangeInput.Value;
                 if params.Drone.MaxRangeKm <= 0
                    error('Drone max range must be positive.');
                end
                params.Drone.UnitCostPerKm = app.DroneUnitCostInput.Value;

                % --- Collect SA Parameters ---
                params.SA.InitialTemp = app.SA_InitialTempInput.Value;
                params.SA.FinalTemp = app.SA_FinalTempInput.Value;
                params.SA.Alpha = app.SA_AlphaInput.Value;
                if params.SA.Alpha <=0 || params.SA.Alpha >=1
                    error('SA Alpha (cooling rate) must be (0, 1).');
                end
                params.SA.MaxIterPerTemp = round(app.SA_MaxIterPerTempInput.Value); % Ensure integer
                if params.SA.InitialTemp <= params.SA.FinalTemp
                    error('SA Initial Temperature must be greater than Final Temperature.');
                end
                if params.SA.MaxIterPerTemp <=0
                    error('SA Iterations per Temp must be positive.');
                end

                % --- Collect ACO Parameters ---
                params.ACO.NumAnts = round(app.ACO_NumAntsInput.Value); % Ensure integer
                params.ACO.MaxIterations = round(app.ACO_MaxIterationsInput.Value); % Ensure integer
                params.ACO.Rho_Evaporation = app.ACO_RhoInput.Value;
                 if params.ACO.Rho_Evaporation < 0 || params.ACO.Rho_Evaporation > 1
                    error('ACO Pheromone Evaporation (rho) must be [0, 1].');
                end
                params.ACO.Alpha_PheromoneImportance = app.ACO_AlphaPheromoneInput.Value;
                params.ACO.Beta_HeuristicImportance = app.ACO_BetaHeuristicInput.Value;
                params.ACO.Tau0_InitialPheromone = app.ACO_Tau0Input.Value;
                if params.ACO.NumAnts <=0 || params.ACO.MaxIterations <=0
                    error('ACO Number of Ants and Max Iterations must be positive.');
                end


                app.ConfigParameters = params; % Store in app property

                configFolderPath = fullfile(app.ProjectBasePath, 'configurations');
                if ~exist(configFolderPath, 'dir')
                    mkdir(configFolderPath);
                    app.StatusTextArea.Value{end+1} = ['Configuration folder created: ', configFolderPath];
                end
                parameterFilePath = fullfile(configFolderPath, 'current_run_parameters.mat');

                save(parameterFilePath, 'params'); % Save the 'params' struct directly

                app.StatusTextArea.Value = {
                    'Parameters successfully collected and validated.';
                    ['Saved to: ', parameterFilePath];
                    ' ';
                    'TO RUN THE SIMULATION:';
                    '1. Ensure this app is closed or minimized.';
                    '2. Open and run "main_Orchestrator.m" from the project root directory.';
                    '(Or, main_Orchestrator.m can be called directly from here if modified)'
                };
                fprintf('INFO: Parameters saved to %s\n', parameterFilePath);
                disp('Collected Parameters:');
                disp(app.ConfigParameters);

            catch ME
                app.StatusTextArea.Value = {['Error: ', ME.message], 'Please correct the input values.'};
                uialert(app.UIFigure, ME.message, 'Parameter Validation Error', 'Icon', 'error');
                return;
            end
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DroneLogisticsConfigurator(varargin)
            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)
            
            % Run startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)
            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Drone Logistics & Hub Optimization Configurator';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            % Create ScenarioLocationTab and its GridLayout
            app.ScenarioLocationTab = uitab(app.TabGroup, 'Title', 'Scenario & Location');
            app.ScenarioGridLayout = uigridlayout(app.ScenarioLocationTab);
            app.LatitudeLabel = uilabel(app.ScenarioGridLayout);
            app.LatitudeLabel.Text = 'Simulation Center Latitude (°):';
            app.LatitudeInput = uieditfield(app.ScenarioGridLayout, 'numeric');
            app.LatitudeInput.Value = 36.0611;
            app.LongitudeLabel = uilabel(app.ScenarioGridLayout);
            app.LongitudeLabel.Text = 'Simulation Center Longitude (°):';
            app.LongitudeInput = uieditfield(app.ScenarioGridLayout, 'numeric');
            app.LongitudeInput.Value = 103.8343;
            app.MapRadiusLabel = uilabel(app.ScenarioGridLayout);
            app.MapRadiusLabel.Text = 'Map Radius (for customer/depot gen., km):';
            app.MapRadiusInput = uieditfield(app.ScenarioGridLayout, 'numeric');
            app.MapRadiusInput.Value = 5;
            app.NumCustomersLabel = uilabel(app.ScenarioGridLayout);
            app.NumCustomersLabel.Text = 'Number of Customers:';
            app.NumCustomersInput = uieditfield(app.ScenarioGridLayout, 'numeric');
            app.NumCustomersInput.Value = 50;
            app.NumCandidateDepotsLabel = uilabel(app.ScenarioGridLayout);
            app.NumCandidateDepotsLabel.Text = 'Number of Candidate Depots (to generate):';
            app.NumCandidateDepotsInput = uieditfield(app.ScenarioGridLayout, 'numeric');
            app.NumCandidateDepotsInput.Value = 20;
            app.NumHubsToSelectLabel = uilabel(app.ScenarioGridLayout);
            app.NumHubsToSelectLabel.Text = 'Number of Hubs to Select:';
            app.NumHubsToSelectInput = uieditfield(app.ScenarioGridLayout, 'numeric');
            app.NumHubsToSelectInput.Value = 5;
            % Create DroneParametersTab and its GridLayout
            app.DroneParametersTab = uitab(app.TabGroup, 'Title', 'Drone Parameters');
            app.DroneGridLayout = uigridlayout(app.DroneParametersTab);
            app.DronePayloadLabel = uilabel(app.DroneGridLayout);
            app.DronePayloadLabel.Text = 'Payload Capacity (units):';
            app.DronePayloadInput = uieditfield(app.DroneGridLayout, 'numeric');
            app.DronePayloadInput.Value = 10;
            app.DroneRangeLabel = uilabel(app.DroneGridLayout);
            app.DroneRangeLabel.Text = 'Max Range (km):';
            app.DroneRangeInput = uieditfield(app.DroneGridLayout, 'numeric');
            app.DroneRangeInput.Value = 15;
            app.DroneUnitCostLabel = uilabel(app.DroneGridLayout);
            app.DroneUnitCostLabel.Text = 'Unit Cost (per km):';
            app.DroneUnitCostInput = uieditfield(app.DroneGridLayout, 'numeric');
            app.DroneUnitCostInput.Value = 0.5;
            % Create SAParametersTab and its GridLayout
            app.SAParametersTab = uitab(app.TabGroup, 'Title', 'SA Parameters');
            app.SAGridLayout = uigridlayout(app.SAParametersTab);
            app.SA_InitialTempLabel = uilabel(app.SAGridLayout);
            app.SA_InitialTempLabel.Text = 'Initial Temperature (T_initial):';
            app.SA_InitialTempInput = uieditfield(app.SAGridLayout, 'numeric');
            app.SA_InitialTempInput.Value = 1000;
            app.SA_FinalTempLabel = uilabel(app.SAGridLayout);
            app.SA_FinalTempLabel.Text = 'Final Temperature (T_final):';
            app.SA_FinalTempInput = uieditfield(app.SAGridLayout, 'numeric');
            app.SA_FinalTempInput.Value = 0.1;
            app.SA_AlphaLabel = uilabel(app.SAGridLayout);
            app.SA_AlphaLabel.Text = 'Cooling Rate (alpha):';
            app.SA_AlphaInput = uieditfield(app.SAGridLayout, 'numeric');
            app.SA_AlphaInput.Value = 0.98;
            app.SA_MaxIterPerTempLabel = uilabel(app.SAGridLayout);
            app.SA_MaxIterPerTempLabel.Text = 'Iterations per Temp:';
            app.SA_MaxIterPerTempInput = uieditfield(app.SAGridLayout, 'numeric');
            app.SA_MaxIterPerTempInput.Value = 250;
            % Create ACOParametersTab and its GridLayout
            app.ACOParametersTab = uitab(app.TabGroup, 'Title', 'ACO Parameters');
            app.ACOGridLayout = uigridlayout(app.ACOParametersTab);
            app.ACO_NumAntsLabel = uilabel(app.ACOGridLayout);
            app.ACO_NumAntsLabel.Text = 'Number of Ants:';
            app.ACO_NumAntsInput = uieditfield(app.ACOGridLayout, 'numeric');
            app.ACO_NumAntsInput.Value = 50;
            app.ACO_MaxIterationsLabel = uilabel(app.ACOGridLayout);
            app.ACO_MaxIterationsLabel.Text = 'Max Iterations:';
            app.ACO_MaxIterationsInput = uieditfield(app.ACOGridLayout, 'numeric');
            app.ACO_MaxIterationsInput.Value = 200;
            app.ACO_RhoLabel = uilabel(app.ACOGridLayout);
            app.ACO_RhoLabel.Text = 'Pheromone Evaporation (rho):';
            app.ACO_RhoInput = uieditfield(app.ACOGridLayout, 'numeric');
            app.ACO_RhoInput.Value = 0.1;
            app.ACO_AlphaPheromoneLabel = uilabel(app.ACOGridLayout);
            app.ACO_AlphaPheromoneLabel.Text = 'Pheromone Importance (alpha_aco):';
            app.ACO_AlphaPheromoneInput = uieditfield(app.ACOGridLayout, 'numeric');
            app.ACO_AlphaPheromoneInput.Value = 1.0;
            app.ACO_BetaHeuristicLabel = uilabel(app.ACOGridLayout);
            app.ACO_BetaHeuristicLabel.Text = 'Heuristic Importance (beta_aco):';
            app.ACO_BetaHeuristicInput = uieditfield(app.ACOGridLayout, 'numeric');
            app.ACO_BetaHeuristicInput.Value = 2.0;
            app.ACO_Tau0Label = uilabel(app.ACOGridLayout);
            app.ACO_Tau0Label.Text = 'Initial Pheromone (tau0):';
            app.ACO_Tau0Input = uieditfield(app.ACOGridLayout, 'numeric');
            app.ACO_Tau0Input.Value = 0.1;
            app.BottomPanelGridLayout = uigridlayout(app.MainGridLayout);
            app.LoadDefaultsButton = uibutton(app.BottomPanelGridLayout, 'push');
            app.LoadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @loadDefaultParameters, true);
            app.LoadDefaultsButton.Text = 'Load Default Parameters';
            app.LoadDefaultsButton.Icon = 'matlabicon.gif'; % Placeholder icon
            app.RunSimulationButton = uibutton(app.BottomPanelGridLayout, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Save Config & Prepare Run';
            app.RunSimulationButton.Icon = matlab.ui.primitive.Icon.RUN_24; % Standard run icon
            app.StatusTextArea = uitextarea(app.BottomPanelGridLayout);
            app.StatusTextArea.Editable = false;
            app.StatusTextArea.Value = {'Welcome!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end
end